classdef NXNQueens < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        INFO0emptycell1queencellLabel  matlab.ui.control.Label
        ChessBoardLabel                matlab.ui.control.Label
        Score                          matlab.ui.control.EditField
        BestFitnessLabel               matlab.ui.control.Label
        ChessBoard                     matlab.ui.control.TextArea
        NQueensVisualizerLabel         matlab.ui.control.Label
        BoardSizeDropDown              matlab.ui.control.DropDown
        BoardSizeDropDownLabel         matlab.ui.control.Label
        MaxGenerationsEditField        matlab.ui.control.NumericEditField
        MaxGenerationsEditFieldLabel   matlab.ui.control.Label
        PopulationSizeEditField        matlab.ui.control.NumericEditField
        PopulationSizeEditFieldLabel   matlab.ui.control.Label
        RunGAButton                    matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunGAButton
        function RunGAButtonPushed(app, event)
            
            app.RunGAButton.BackgroundColor = [0.93,0.69,0.13];
            %% controling paramters of the GA algortihm
            Problem.obj = @fitness;
            
            M = app.PopulationSizeEditField.Value;            
            switch app.BoardSizeDropDown.Value
                case '4X4'
                    N = 4;
                case '8X8'
                    N = 8;
                otherwise   
                    N = 16;
            end
            
            MaxGen =app.MaxGenerationsEditField.Value;
            
            Pc = 1; %probability of recombination 
            Pm = 0.8; %probability of mutation
            visualization = 1; % set to 0 if you do not want the convergence curve 
            [BestChrom,cgcurve]  = GeneticAlgorithm (M , N, MaxGen , Pc, Pm , Problem.obj , visualization);
            %disp('The best chromosome found: ')
            %BestChrom.Gene
            %disp('The best fitness value: ')
            %BestChrom.Fitness
            plot(app.UIAxes,1 : MaxGen , cgcurve);
            fit_mat = zeros(N);
            for q = 1:N
                row = BestChrom.Gene(q);
                fit_mat(row,q)=1;
            end
            app.ChessBoard.Value=cellstr(num2str(fit_mat));
            app.Score.Value =string(BestChrom.Fitness);
            pause(1)
            app.RunGAButton.BackgroundColor = [0.19,0.36,0.43];


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Convergence Curve')
            xlabel(app.UIAxes, 'Generation')
            ylabel(app.UIAxes, 'Fitness of the best Solution')
            zlabel(app.UIAxes, 'Z')
            app.RunGAButton = uibutton(app.GridLayout, 'push');
            app.RunGAButton.ButtonPushedFcn = createCallbackFcn(app, @RunGAButtonPushed, true);
            app.RunGAButton.Text = 'Run GA';
            app.PopulationSizeEditFieldLabel = uilabel(app.GridLayout);
            app.PopulationSizeEditFieldLabel.Text = 'Population Size';
            app.PopulationSizeEditField = uieditfield(app.GridLayout, 'numeric');
            app.PopulationSizeEditField.Limits = [2 100000];
            app.PopulationSizeEditField.RoundFractionalValues = 'on';
            app.PopulationSizeEditField.Value = 100;
            app.MaxGenerationsEditFieldLabel = uilabel(app.GridLayout);
            app.MaxGenerationsEditFieldLabel.Text = 'Max Generations';
            app.MaxGenerationsEditField = uieditfield(app.GridLayout, 'numeric');
            app.MaxGenerationsEditField.Value = 1000;
            app.BoardSizeDropDownLabel = uilabel(app.GridLayout);
            app.BoardSizeDropDownLabel.Text = 'Board Size';
            app.BoardSizeDropDown = uidropdown(app.GridLayout);
            app.BoardSizeDropDown.Items = {'4X4', '8X8', '16X16'};
            app.BoardSizeDropDown.Value = '4X4';
            app.NQueensVisualizerLabel = uilabel(app.GridLayout);
            app.NQueensVisualizerLabel.Text = 'N Queens Visualizer';
            app.ChessBoard = uitextarea(app.GridLayout);
            app.BestFitnessLabel = uilabel(app.GridLayout);
            app.BestFitnessLabel.Text = 'Best Fitness';
            app.Score = uieditfield(app.GridLayout, 'text');
            app.ChessBoardLabel = uilabel(app.GridLayout);
            app.ChessBoardLabel.Text = {'Chess Board'; ''};
            app.INFO0emptycell1queencellLabel = uilabel(app.GridLayout);
            app.INFO0emptycell1queencellLabel.Text = {'INFO :'; '0 = empty cell'; '1= queen cell'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NXNQueens

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end